classdef app4_UI_Prototype < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        NewMenu                        matlab.ui.container.Menu
        OpenMenu                       matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        ExportasPDFMenu                matlab.ui.container.Menu
        EditMenu                       matlab.ui.container.Menu
        CutMenu                        matlab.ui.container.Menu
        CopyMenu                       matlab.ui.container.Menu
        PasteMenu                      matlab.ui.container.Menu
        RunMenu                        matlab.ui.container.Menu
        RunOptimizerMenu               matlab.ui.container.Menu
        RunMotionPlannerMenu           matlab.ui.container.Menu
        TestsMenu                      matlab.ui.container.Menu
        RunCase1Menu                   matlab.ui.container.Menu
        RunCase2Menu                   matlab.ui.container.Menu
        RunCase3Menu                   matlab.ui.container.Menu
        RunCase4Menu                   matlab.ui.container.Menu
        RunCase5Menu                   matlab.ui.container.Menu
        Toolbar                        matlab.ui.container.Toolbar
        PushTool                       matlab.ui.container.toolbar.PushTool
        PushTool2                      matlab.ui.container.toolbar.PushTool
        PushTool3                      matlab.ui.container.toolbar.PushTool
        Pointer                        matlab.ui.container.toolbar.ToggleTool
        Target                         matlab.ui.container.toolbar.ToggleTool
        Obstacle                       matlab.ui.container.toolbar.ToggleTool
        Eraser                         matlab.ui.container.toolbar.ToggleTool
        Run                            matlab.ui.container.toolbar.PushTool
        Stop                           matlab.ui.container.toolbar.PushTool
        PushTool4                      matlab.ui.container.toolbar.PushTool
        Animate                        matlab.ui.container.toolbar.PushTool
        RestoreView                    matlab.ui.container.toolbar.PushTool
        GridLayout6                    matlab.ui.container.GridLayout
        TabGroup3                      matlab.ui.container.TabGroup
        OptimizerTab                   matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup2                      matlab.ui.container.TabGroup
        RobotParametersTab             matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        FirstangleEditField            matlab.ui.control.NumericEditField
        FirstangleEditFieldLabel       matlab.ui.control.Label
        MaxlengthEditFieldLabel        matlab.ui.control.Label
        MaxlengthEditField             matlab.ui.control.NumericEditField
        MinlengthEditFieldLabel        matlab.ui.control.Label
        MinlengthEditField             matlab.ui.control.NumericEditField
        MaxangleEditField              matlab.ui.control.NumericEditField
        MaxangleEditFieldLabel         matlab.ui.control.Label
        MinangleEditField              matlab.ui.control.NumericEditField
        MinangleEditFieldLabel         matlab.ui.control.Label
        LinksEditField                 matlab.ui.control.NumericEditField
        LinksEditFieldLabel            matlab.ui.control.Label
        FixedfirstangleCheckBox        matlab.ui.control.CheckBox
        OptimizationAlgorithmTab       matlab.ui.container.Tab
        GridLayout4                    matlab.ui.container.GridLayout
        CrunchmethodDropDownLabel      matlab.ui.control.Label
        CrunchmethodDropDown           matlab.ui.control.DropDown
        MutationmethodDropDownLabel    matlab.ui.control.Label
        MutationmethodDropDown         matlab.ui.control.DropDown
        CrossoverprobabilitySpinnerLabel  matlab.ui.control.Label
        CrossoverprobabilitySpinner    matlab.ui.control.Spinner
        CrossovermethodDropDownLabel   matlab.ui.control.Label
        CrossovermethodDropDown        matlab.ui.control.DropDown
        PenaltymethodDropDownLabel     matlab.ui.control.Label
        PenaltymethodDropDown          matlab.ui.control.DropDown
        IndividualsEditFieldLabel      matlab.ui.control.Label
        IndividualsEditField           matlab.ui.control.NumericEditField
        GenerationsEditFieldLabel      matlab.ui.control.Label
        GenerationsEditField           matlab.ui.control.NumericEditField
        AlgorithmDropDown              matlab.ui.control.DropDown
        AlgorithmDropDownLabel         matlab.ui.control.Label
        SurvivalalphaEditField         matlab.ui.control.NumericEditField
        SurvivalalphaLabel             matlab.ui.control.Label
        SurvivalmethodDropDown         matlab.ui.control.DropDown
        SurvivalmethodDropDownLabel    matlab.ui.control.Label
        MutationprobabilitySpinner     matlab.ui.control.Spinner
        MutationprobabilitySpinnerLabel  matlab.ui.control.Label
        SlectionmethodDropDown         matlab.ui.control.DropDown
        SlectionmethodDropDownLabel    matlab.ui.control.Label
        ObstacleavoidanceCheckBox      matlab.ui.control.CheckBox
        RankAlgorithmTab               matlab.ui.container.Tab
        GridLayout5                    matlab.ui.container.GridLayout
        StepIKEditFieldLabel           matlab.ui.control.Label
        StepIKEditField                matlab.ui.control.NumericEditField
        SteplengthEditField            matlab.ui.control.NumericEditField
        SteplengthEditFieldLabel       matlab.ui.control.Label
        MethodDropDown                 matlab.ui.control.DropDown
        MethodDropDownLabel            matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        PropertiesTab                  matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        AngledegEditField              matlab.ui.control.NumericEditField
        AngledegEditFieldLabel         matlab.ui.control.Label
        LengthEditField                matlab.ui.control.NumericEditField
        LengthEditFieldLabel           matlab.ui.control.Label
        YcoordinateEditField           matlab.ui.control.NumericEditField
        YcoordinateEditFieldLabel      matlab.ui.control.Label
        XcoordinateEditField           matlab.ui.control.NumericEditField
        XcoordinateEditFieldLabel      matlab.ui.control.Label
        OptionsLabel                   matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Tree                           matlab.ui.container.Tree
        BaseNode                       matlab.ui.container.TreeNode
        TargetsNode                    matlab.ui.container.TreeNode
        ObstaclesNode                  matlab.ui.container.TreeNode
        ObjectsLabel                   matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        OPTree                         matlab.ui.container.Tree
        Label                          matlab.ui.control.Label
        UIAxes1                        matlab.ui.control.UIAxes
        MotionPlannerTab               matlab.ui.container.Tab
        GridLayout7                    matlab.ui.container.GridLayout
        AnimationsLabel                matlab.ui.control.Label
        GridLayout8                    matlab.ui.container.GridLayout
        SpeedperFrame1secto0secSlider  matlab.ui.control.Slider
        SpeedperFrame1secto0secSliderLabel  matlab.ui.control.Label
        Button_5                       matlab.ui.control.Button
        MPTree                         matlab.ui.container.Tree
        NextanimationNode              matlab.ui.container.TreeNode
        TabGroup4                      matlab.ui.container.TabGroup
        StepSizeTab                    matlab.ui.container.Tab
        GridLayout3_3                  matlab.ui.container.GridLayout
        RetractingEditField            matlab.ui.control.NumericEditField
        RetractingEditFieldLabel       matlab.ui.control.Label
        GrowingEditField               matlab.ui.control.NumericEditField
        GrowingEditFieldLabel          matlab.ui.control.Label
        SteeringEditField              matlab.ui.control.NumericEditField
        SteeringEditField_3Label       matlab.ui.control.Label
        BaseRotateCheckBox             matlab.ui.control.CheckBox
        SimultaneousCheckBox           matlab.ui.control.CheckBox
        TypeofheuristicDropDown        matlab.ui.control.DropDown
        TypeofheuristicDropDownLabel   matlab.ui.control.Label
        TypeofalgorithmDropDown        matlab.ui.control.DropDown
        TypeofalgorithmDropDownLabel   matlab.ui.control.Label
        GrowingandretractingEditField  matlab.ui.control.NumericEditField
        GrowingandretractingEditFieldLabel  matlab.ui.control.Label
        SteeringEditField_2            matlab.ui.control.NumericEditField
        SteeringEditField_2Label       matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        ContextMenu                    matlab.ui.container.ContextMenu
        AnimateMenu                    matlab.ui.container.Menu
    end

    
    properties (Access = public)
        op % Description
        sp;
        gas
        

        bbbcs
        algorithm
        stopRunItGeneric % Description
        minMax;
        UIAxes; % Description
        nChar % Description
        tabChar ;
    end
    
    properties (Access = private)
        XLim = [-50 200];
            
        YLim = [-50 200]; % Description % Description
        loadedData % Description
        dragDropStart % Description
        latestTool = "arrow" % Description
        
        lastSelected;
        
        mpPath; % Description
        clipboard; % Used for cut, copy, and paste
        animationsCount; % Only used for naming new nodes in MPTree
        MPRunning; % boolean, true: if an animation is being animated, false: otherwise
        previousX % previous X in MP
        previousY % previous Y in MP
    end
    

    methods (Access = private)

        function ps_mouse_click(app,src)
            if src.Selected == "off"
                app.Tree.SelectedNodes = src.UserData;
                node_click(app);
            else
                disable_properties(app);
                src.Selected = "off";
            end
            if app.Eraser.State == "on"

                if app.Tree.SelectedNodes.Parent == app.ObstaclesNode || app.Tree.SelectedNodes.Parent == app.TargetsNode
                    if numel(app.OPTree.Children)>1
                        result = confirmAction(app,'Editing',"Loss in Optimizer's Output Will Occur");
                        if result  == "OK"
                            delete(app.OPTree.Children);
                        else
                            return;
                        end

                    end
                    deleteSelected(app);
                    app.lastSelected = app.BaseNode.Children(end).NodeData.child;
                    % change cursor to arrow
                     PointerOn(app,0);

                end
            end


        end


        function select_child(app,child)
            try
                app.lastSelected.Selected = "off";
            catch
            end
            child.Selected = "on";
            app.lastSelected = child;
        end


        function node = add_obstacle_node(app,ps,x,y,radius)
            % adds an obstacle node to the right tree
            data = struct('child', ps, 'x', x, 'y', y,'radius',radius);
            text = "x: " + string(round(x,2)) + ", y: " + string(round(y,2)) + ", r: " + string(round(radius,2));
            node = uitreenode(app.ObstaclesNode,"NodeData",data,"Text",text);
            
            % msgbox(string(app.ObstaclesNode.Children(1).NodeData.x))
        end


        function node = add_target_node(app,ps,x,y,length,angle)
            % adds a target node to the right tree
            data = struct('child', ps, 'x', x, 'y', y,'length',length,'angle',angle);
            text = "x: " + string(round(x,2)) + ", y: " + string(round(y,2)) + ", angle: " + string(round(angle,2));
            node =  uitreenode(app.TargetsNode,"NodeData",data,"Text",text);
            % msgbox(string(app.ObstaclesNode.Children(1).NodeData.x))
        end


        function node = add_base_node(app,src,x,y,angle)
             % adds the base node to the right tree
            data = struct('child', src, 'x', x, 'y', y,'angle',angle);
            text = "x: " + string(round(x,2)) + ", y: " + string(round(y,2)) + ", angle: " + string(round(angle,2));
            node =  uitreenode(app.BaseNode,"NodeData",data,"Text",text);
        end


        function node_click(app)
            
            selectedNodes = app.Tree.SelectedNodes;
            if app.Eraser.State == "on"
                % when erasing, if you click on a node, it selects a child
                select_child(app,selectedNodes.NodeData.child);

            elseif selectedNodes.Parent == app.ObstaclesNode || selectedNodes.Parent == app.TargetsNode || selectedNodes.Parent == app.BaseNode
                select_child(app,selectedNodes.NodeData.child);
    
                app.TabGroup.SelectedTab = app.PropertiesTab;
                % msgbox(string(selectedNodes.NodeData.x))
                if selectedNodes.Parent == app.TargetsNode || selectedNodes.Parent == app.BaseNode
                    app.XcoordinateEditField.Value = selectedNodes.NodeData.x;
                    app.YcoordinateEditField.Value = selectedNodes.NodeData.y;
                    app.LengthEditField.Value = 1;
                    app.AngledegEditField.Value = selectedNodes.NodeData.angle;
                    app.LengthEditFieldLabel.Text = "length";
                    app.LengthEditField.Enable="off";
                    app.LengthEditFieldLabel.Enable="off";
                    enable_properties(app,"target")
                elseif selectedNodes.Parent == app.ObstaclesNode
                    app.XcoordinateEditField.Value = selectedNodes.NodeData.x;
                    app.YcoordinateEditField.Value = selectedNodes.NodeData.y;
                    app.LengthEditField.Value = selectedNodes.NodeData.radius;
                    app.LengthEditFieldLabel.Text = "radius";
                    app.AngledegEditField.Enable ="off";
                    app.AngledegEditFieldLabel.Enable ="off";
                    enable_properties(app,"obstacle")
                end
            else 
                %  deselect 
            end
        end
        
        
        function create_polyshape(app,x,y,length,angle,target)
            xLimits = app.UIAxes1.XLim;
                
            yLimits = app.UIAxes1.YLim;
            
            % Set 'NextPlot' to 'add' to allow adding shapes without clearing
            % app.UIAxes1.NextPlot = 'add';
            
            % Draw your shapes or add data here
            % For example, draw a rectangle:
            
            
            if ~target
                radius  = length;
                ps = draw_obstacle(app,x,y,radius,app.UIAxes1);
                node = add_obstacle_node(app,ps,x,y,radius);
                
            elseif target
                ps = draw_target(app,x,y,angle,app.UIAxes1);
                node = add_target_node(app,ps,x,y,length,angle);
            end
            ps.UserData = node;
            ps.FaceAlpha = 0.1;
            % ps.HitTest = "off";
            ps.ButtonDownFcn = @(src,event)ps_mouse_click(app,src);
            ps_mouse_click(app,ps);
            
            % Store the current axis limits
            % Restore the original axis limits
            app.UIAxes1.XLim = xLimits;
            app.UIAxes1.YLim = yLimits;
            
            % Set 'NextPlot' back to 'replace' to prevent further automatic additions
            % app.UIAxes1.NextPlot = 'replace';
            app.Tree.expand;

        end


        function deselect_children(app)
            app.lastSelected.Selected = "off";
        end
        

        function enable_properties(app,class)
            if class=="target"
                app.AngledegEditField.Enable="on";
                app.AngledegEditFieldLabel.Enable="on";
            elseif class=="obstacle"
                app.LengthEditFieldLabel.Enable="on";
                app.LengthEditField.Enable="on";
            end
            app.XcoordinateEditField.Enable = "on";
            app.YcoordinateEditField.Enable = "on";
            app.XcoordinateEditFieldLabel.Enable = "on";
            app.YcoordinateEditFieldLabel.Enable= "on";
            
        end


        function disable_properties(app)
            app.XcoordinateEditFieldLabel.Enable = "off";
            app.YcoordinateEditFieldLabel.Enable= "off";
            app.LengthEditFieldLabel.Enable="off";
            app.AngledegEditFieldLabel.Enable="off";
            app.XcoordinateEditField.Value = 0;
            app.YcoordinateEditField.Value = 0;
            app.LengthEditField.Value=0;
            app.AngledegEditField.Value=0;
            app.XcoordinateEditField.Enable = "off";
            app.YcoordinateEditField.Enable = "off";
            app.LengthEditField.Enable="off";
            app.AngledegEditField.Enable="off";
        end
        

        function add_base_plot_and_node(app,x,y,angle)
            ps = draw_base(app,x,y,angle,app.UIAxes1);
            
            node = add_base_node(app,ps,x,y,angle);
            app.Tree.SelectedNodes = node;
            ps.UserData = node;
            ps.ButtonDownFcn = @(src,event)ps_mouse_click(app,src);
            app.Tree.expand;
            handle_hittest(app)
        end
        

        function deselect_toggles(app,event)
            children = app.Toolbar.Children;
            child = event.Source;
            for i = 1:length(children)
                if child ~= children(i)
                    try
                    children(i).State = "off";
                    catch
                    end
                end
            end
            
        end


        function turn_children_hittest(app,value)
            children = app.UIAxes1.Children;
            for i = 1:length(children)
                children(i).HitTest = value;
            end
        end


        function changePointerTo(app,tool)
            if tool=="target" || tool == "eraser"
                            app.UIFigure.Pointer = "custom";
                if tool == "eraser"
                    matrix = [   nan nan nan nan nan nan nan nan nan nan nan nan nan nan nan nan;
                                 nan nan nan nan nan nan nan nan 1 1 1 1 1 nan nan nan;
                                 nan nan nan nan nan nan nan 1 1 nan nan nan 1 1 nan nan;
                                 nan nan nan nan nan nan 1 1 nan nan nan nan nan 1 1 nan;
                                 nan nan nan nan nan 1 1 nan nan nan nan nan nan nan 1 1;
                                 nan nan nan nan 1 1 nan nan nan nan nan nan nan nan 1 1;
                                 nan nan nan 1 1 1 nan nan nan nan nan nan nan nan nan 1;
                                 nan nan 1 1 nan 1 1 nan nan nan nan nan nan nan 1 1;
                                 nan 1 1 nan nan nan 1 1 nan nan nan nan nan 1 1 nan;
                                 1 1 nan nan nan nan nan 1 1 nan nan nan 1 1 nan nan;
                                 1 nan nan nan nan nan nan nan 1 1 nan 1 1 nan nan nan;
                                 1 nan nan nan nan nan nan nan nan 1 1 1 nan nan nan nan;
                                 1 1 nan nan nan nan nan nan nan 1 1 nan nan nan nan nan;
                                 nan 1 1 nan nan nan nan nan 1 1 nan nan nan nan nan nan;
                                 nan nan 1 1 1 1 1 1 1 1 1 1 1 1 1 1;
                                 nan nan nan nan nan nan nan nan nan nan nan nan nan nan nan nan;
                             ];
                    app.UIFigure.PointerShapeHotSpot = [16 1];
                    app.latestTool = tool;
                elseif tool == "target"
                    % Create a 16x16 matrix with NaN values
                    matrix = [ nan nan nan nan nan nan nan nan nan nan nan nan nan nan nan nan
                             nan nan nan nan nan nan nan nan nan nan nan nan nan nan nan nan
                             nan nan nan nan nan nan nan 1 1 nan nan nan nan nan nan nan
                             nan nan nan nan nan nan nan 1 1 nan nan nan nan nan nan nan
                             nan nan nan nan nan nan nan 1 1 nan nan nan nan nan nan nan
                             nan nan nan nan nan nan 1 1 1 1 nan nan nan nan nan nan
                             nan 1 1 1 1 1 1 nan nan 1 1 1 1 1 1 nan
                             nan nan 1 1 1 nan nan nan nan nan nan 1 1 1 nan nan
                             nan nan nan 1 1 1 nan nan nan nan 1 1 1 nan nan nan
                             nan nan nan nan nan 1 nan nan nan nan 1 nan nan nan nan nan
                             nan nan nan nan nan 1 nan nan nan nan 1 nan nan nan nan nan
                             nan nan nan nan 1 1 1 1 1 1 1 1 nan nan nan nan
                             nan nan nan nan 1 1 1 nan nan 1 1 1 nan nan nan nan
                             nan nan nan nan 1 1 nan nan nan nan 1 1 nan nan nan nan
                             nan nan nan 1 nan nan nan nan nan nan nan nan 1 nan nan nan
                             nan nan nan nan nan nan nan nan nan nan nan nan nan nan nan nan
                            ];
                    app.UIFigure.PointerShapeHotSpot = [8 8];
                    app.latestTool = tool;
                
         
                end
            app.UIFigure.PointerShapeCData = matrix;
            else 
                app.UIFigure.Pointer = tool;
                app.latestTool = tool;
            end
        end

        %% Variable Creation

        function create_base_variable(app)
            base = app.BaseNode.Children(1);
            app.op.home_base = [base.NodeData.x base.NodeData.y base.NodeData.angle];
        end
        function create_targets_variable(app)
            nodes = app.TargetsNode.Children;
            t_v =  zeros(length(nodes),3);
            for i = 1:length(nodes)
                t_v(i,1)= nodes(i).NodeData.x;
                t_v(i,2)= nodes(i).NodeData.y;
                t_v(i,3)= nodes(i).NodeData.angle;
            end
            app.op.targets = t_v;
        end
        function create_obstacles_variable(app)
            nodes = app.ObstaclesNode.Children;
            o_v =  zeros(length(nodes),3);
            for i = 1:length(nodes)
                o_v(i,1)= nodes(i).NodeData.x;
                o_v(i,2)= nodes(i).NodeData.y;
                o_v(i,3)= nodes(i).NodeData.radius;
            end
            app.op.obstacles = o_v ;     
        end
        function create_parameters_variables(app)
            app.op.n_nodes = app.LinksEditField.Value;
            app.op.angle_domain = [app.MinangleEditField.Value, app.MaxangleEditField.Value];
            app.op.length_domain = [app.MinlengthEditField.Value , app.MaxlengthEditField.Value];

            app.op.first_angle.is_fixed = app.FixedfirstangleCheckBox.Value;
            app.op.first_angle.angle = app.FirstangleEditField.Value;
        end

        %%  

        function collectOP(app)
            create_base_variable(app);
            create_targets_variable(app);
            create_obstacles_variable(app);
            create_parameters_variables(app);

        end


        function sendOP(app)
            loadOP(app.op, 0);
        end

        function collectSP(app,from,to)
            nextAnimation = app.MPTree.Children(end);
            app.sp.start_conf = round(app.OPTree.SelectedNodes.NodeData.configurations(:,:,from),3);

            app.sp.design = round(app.OPTree.SelectedNodes.NodeData.design,3);
            app.sp.steerBounds = round(app.op.angle_domain,3);
            app.sp.lengthMin = round(app.op.length_domain(1),3);
            app.sp.costArray = round(nextAnimation.NodeData.costArray,3);
            app.sp.stepSize =  round(nextAnimation.NodeData.stepSize,3);
            app.sp.obstacles = round(app.op.obstacles,3);
            app.sp.goals = round(app.OPTree.SelectedNodes.NodeData.configurations(:,:,to),3);
            app.sp.home_base = round(app.op.home_base,3);
            typeOfAlg = nextAnimation.NodeData.typeOfAlg;
            switch(typeOfAlg)
                case "A*"
                    app.sp.typeOfAlg = "astar";
                case "UCS"
                    app.sp.typeOfAlg = "ucs";
                case "Greedy"
                    app.sp.typeOfAlg = "greedy";
            end
            
            typeOfHeuristic = nextAnimation.NodeData.typeOfHeuristic;
            switch (typeOfHeuristic)
                case "Continuous"
                    app.sp.typeOfHeuristic = "continue";
                case "Discrete"
                    app.sp.typeOfHeuristic = "discrete";
            end
            
            app.sp.isSimulataneously = nextAnimation.NodeData.isSimulataneously;
            app.sp.baseRotate = nextAnimation.NodeData.baseRotate;


        end
        %% 


        function remove_segment_lines(app)
            children = app.UIAxes1.Children;
            for i = 1:length(children)
                if isempty(children(i).UserData)
                    delete(children(i))

                end
            end
        end

        
        function value = doesOverlap(app)

            children_shapes = objectsShapes(app);
            
            TF = overlaps(children_shapes);
            s = sum(TF,'all');
            if s > length(children_shapes)
                uialert(app.UIFigure,"Intersections found","Warning","Icon","warning")
                value = true;
                return
            end
            value = false;
        end
        
        function  resetOptions(app)
            disable_properties(app)
            app.LinksEditField.Value = 0;
            app.MinangleEditField.Value = 0;
            app.MaxangleEditField.Value = 0;
            app.MinlengthEditField.Value=0;
            app.MaxlengthEditField.Value = 0;
            app.FirstangleEditField.Value = 0;
            app.FixedfirstangleCheckBox.Value=false;
        end
        
        function value = importFile(app)
            [fileName, pathName] = uigetfile('*.mat', 'Select a .mat file');
    
            if ischar(fileName) && ischar(pathName)
                % File selected successfully
                fullPath = fullfile(pathName, fileName);
                
                % Now, you can load the .mat file or process it as needed
                % For example, you can use the "load" function to load the data:
                app.loadedData = load(fullPath);
                value = true;
                return
                % Perform further actions with the loaded data
                
            end
            value = false;
        end
        
        function value = confirmAction(app,title,msg)
            fig = app.UIFigure;
            selection = uiconfirm(fig,msg,title, ...
                "Options",["OK","Cancel"], ...
                "DefaultOption",2,"CancelOption",2);
            value = selection;

        end
        
        function newDocument(app)
            resetOptions(app)
            remove_segment_lines(app)
            disable_properties(app)
            
            children = app.UIAxes1.Children;
            for i = 1:length(children)
                delete(children(i).UserData)
                delete(children(i))
            end

            add_base_plot_and_node(app,0,0,0);
            
            delete(app.OPTree.Children);

            while numel(app.MPTree.Children) > 1
                delete(app.MPTree.Children(1));
            end

            cla(app.UIAxes2)

        end
        
        function parseDataIntoOP(app)
            app.op = app.loadedData.saveVar.op;

            % Delete default base
            delete(app.BaseNode.Children(1))
            delete(app.UIAxes1.Children(1))

            % Add a base
            base = app.op.home_base;
            add_base_plot_and_node(app,base(1),base(2),base(3))

            % Add obstacles
            obstacles = app.op.obstacles;
            if ~isempty(obstacles)
                for i = 1:length(obstacles(:,1))
                    create_polyshape(app,obstacles(i,1),obstacles(i,2),obstacles(i,3),0,false);
                end
            end

            % Add targets 
            targets = app.op.targets;
            for i = 1 : length(targets(:,1))
                create_polyshape(app,targets(i,1),targets(i,2),1,targets(i,3),true);
            end
            
            % Add n_nodes
            n_nodes = app.op.n_nodes;
            app.LinksEditField.Value = n_nodes;

            % Add angle domain
            min_angle = app.op.angle_domain(1);
            max_angle = app.op.angle_domain(2);

            app.MinangleEditField.Value = min_angle;
            app.MaxangleEditField.Value = max_angle;

            % Add length domain;
            min_length = app.op.length_domain(1);
            max_length = app.op.length_domain(2);

            app.MinlengthEditField.Value = min_length;
            app.MaxlengthEditField.Value = max_length;

            % Add first angle
            angle = app.op.first_angle.angle;
            is_fixed = app.op.first_angle.is_fixed;

            app.FirstangleEditField.Value = angle;
            app.FixedfirstangleCheckBox.Value = is_fixed;

            init_segments(app);
        end
        
        function loadDefaultOptions(app)
            % robot parameters
            app.LinksEditField.Value = 20;
            app.MinangleEditField.Value = -45;
            app.MaxangleEditField.Value = 45;
            app.MinlengthEditField.Value= 10;
            app.MaxlengthEditField.Value = 40;
            app.FirstangleEditField.Value = 0;
            app.FixedfirstangleCheckBox.Value = true;
            
            % optimization algorithm
            app.AlgorithmDropDown.Value = "Genetic Algorithm";
            app.GenerationsEditField.Value = 100;
            app.IndividualsEditField.Value = 500;
            app.PenaltymethodDropDown.Value = "static";
            app.ObstacleavoidanceCheckBox.Value = true;

            % ga
            app.SlectionmethodDropDown.Value = "tournament";
            app.CrossovermethodDropDown.Value = "blxa";
            app.CrossoverprobabilitySpinner.Value = 0.9;
            app.MutationmethodDropDown.Value = "random";
            app.MutationprobabilitySpinner.Value = 0.4;
            app.SurvivalmethodDropDown.Value = "elitist_full";
            app.SurvivalalphaEditField.Value = 40;
            app.SurvivalalphaEditField.Visible = "off";
            app.SurvivalalphaLabel.Visible = "off";

            % bbbc
            app.CrunchmethodDropDown.Value = "fittest";

            % rank partitioning
            app.MethodDropDown.Value = "penalty";
            app.StepIKEditField.Value = 0.5;
            app.SteplengthEditField.Value = 5;

            % motion planner cost
            app.SteeringEditField.Value = 1;
            app.GrowingEditField.Value = 1;
            app.RetractingEditField.Value = 1;
            
            % motion planner step size
            app.SteeringEditField_2.Value = 2.5;
            app.GrowingandretractingEditField.Value = 10;

            % motion planner's parameters
            app.SimultaneousCheckBox.Value = false;
            app.BaseRotateCheckBox.Value = false;
            app.SpeedperFrame1secto0secSlider.Value = 95;
        end
        
        function Apply(app)
            selectedNode = app.Tree.SelectedNodes;
            child = selectedNode.NodeData.child;
            delete(child);
            x = app.XcoordinateEditField.Value;
            y = app.YcoordinateEditField.Value;
            selectedNode.NodeData.x=x;
            selectedNode.NodeData.y=y;
            if selectedNode.Parent == app.TargetsNode
                % L = app.LengthEditField.Value;
                angle = app.AngledegEditField.Value ;
                selectedNode.NodeData.length = 2;
                selectedNode.NodeData.angle = angle;
                selectedNode.Text = "x: " + string(round(x,2)) + ", y: " + string(round(y,2)) + ", angle: " + string(round(angle,2));
                ps = draw_target(app,x,y,angle,app.UIAxes1);
                
            elseif selectedNode.Parent == app.ObstaclesNode
                radius = app.LengthEditField.Value;
                selectedNode.NodeData.radius = radius;
                selectedNode.Text = "x: " + string(round(x,2)) + ", y: " + string(round(y,2)) + ", radius: " + string(round(radius,2));
                ps = draw_obstacle(app,x,y,radius,app.UIAxes1);
            elseif selectedNode.Parent == app.BaseNode
                angle = app.AngledegEditField.Value ;
                selectedNode.NodeData.length = 1;
                selectedNode.NodeData.angle = angle;
                selectedNode.Text = "x: " + string(round(x,2)) + ", y: " + string(round(y,2)) + ", angle: " + string(round(angle,2));
                ps = draw_base(app,x,y,angle,app.UIAxes1);
            end
            
            ps.UserData = selectedNode;
            ps.FaceAlpha = 0.1;
            selectedNode.NodeData.child = ps;
            ps.ButtonDownFcn = @(src,event)ps_mouse_click(app,src);
            ps_mouse_click(app,ps);


        end
        
        function init_segments(app)
            
            remove_segment_lines(app)
            
            collectOP(app);
            sendOP(app);
            draw_segments(app,app.UIAxes1);
            handle_hittest(app)                


        end
        
        function drawRobotConfigurations(app,configs)
            % from main script drawProblem2D
            n_targets = size(app.op.targets,1);
            if isempty(configs) == false
            for i=1:1:n_targets
                conf = configs(:,:,i);
                xy = solveForwardKinematics2D(conf,app.op.home_base,false); % solve forward kinematics to draw the robot configuration
                for j = 1 : 1 : size(xy,1)-1
                    plot(app.UIAxes1,[xy(j,1),xy(j+1,1)],[xy(j,2),xy(j+1,2)],'-o','Color','r'); % print each node of the robot configuration
                end  
            end
            end

        end


        function handle_hittest(app)
            pointer = app.UIFigure.Pointer;
            if pointer == "arrow" || app.Eraser.State == "on"
                turn_children_hittest(app,"on")
            else
                turn_children_hittest(app,"off")
            end
        end
        
        function deleteSelected(app)
            selectedNodes = app.Tree.SelectedNodes;
            % if selectedNodes.Parent == app.TargetsNode || selectedNodes.Parent == app.ObstaclesNode
                delete(selectedNodes.NodeData.child);
                delete(selectedNodes);
                % deselect(app)
                init_segments(app);
            % end
        end
        
        function bbbcOptionsVisibility(app,value)
            app.CrunchmethodDropDown.Visible = value;
            app.CrunchmethodDropDownLabel.Visible = value;
        end
        
        function gaOptionsVisibility(app,value)
            app.SlectionmethodDropDown.Visible = value;
            app.SlectionmethodDropDownLabel.Visible = value;
            app.CrossovermethodDropDown.Visible = value;
            app.CrossovermethodDropDownLabel.Visible = value;
            app.CrossoverprobabilitySpinner.Visible = value;
            app.CrossoverprobabilitySpinnerLabel.Visible = value;
            app.MutationmethodDropDown.Visible = value;
            app.MutationmethodDropDownLabel.Visible = value;
            app.MutationprobabilitySpinner.Visible = value;
            app.MutationprobabilitySpinnerLabel.Visible = value;
            app.SurvivalmethodDropDown.Visible = value;
            app.SurvivalmethodDropDownLabel.Visible = value;
            app.SurvivalalphaEditField.Visible = value;
            app.SurvivalalphaLabel.Visible = value;
            updateSurvivalAlphaVisibility(app,value)
        end
        
        function sendRunItGeneric(app)
            collectRunItGeneric(app);
            loadRunItGeneric(app.algorithm,app.gas,app.bbbcs);
        end
        function collectRunItGeneric(app)
            if app.AlgorithmDropDown.Value == "Genetic Algorithm"
                app.algorithm = "ga";
            elseif app.AlgorithmDropDown.Value == "Big-Bang Big-Crunch"
                app.algorithm = "bbbc";
            end
            
            switch app.algorithm
                case "ga"
                    app.gas.generations = app.GenerationsEditField.Value;
                    app.gas.n_individuals = app.IndividualsEditField.Value;
                    app.gas.obstacle_avoidance = app.ObstacleavoidanceCheckBox.Value;
                    app.gas.selection_method = app.SlectionmethodDropDown.Value;    % 'tournament', 'proportionate'
                    app.gas.crossover_method = app.CrossovermethodDropDown.Value;  % 'blxa'
                    app.gas.crossover_probability = app.CrossoverprobabilitySpinner.Value;
                    app.gas.mutation_method = app.MutationmethodDropDown.Value;   % 'random', 'modifiedRandom'
                    app.gas.mutation_probability = app.MutationprobabilitySpinner.Value;  % -1 is dynamic 
                    app.gas.survival_method = app.SurvivalmethodDropDown.Value; % 'elitist_full', 'elitist_alpha'
                    app.gas.survival_alpha = app.SurvivalalphaEditField.Value;    %this is the percentage of elites that will stay in the new population
                    app.gas.penalty_method = app.PenaltymethodDropDown.Value;	% 'static', 'deb'
                    % settings of rank partitioning algorithm
                    app.gas.ranking_method = app.MethodDropDown.Value;     % 'penalty', 'separation'
                    app.gas.rankingSettings.step_ik = app.StepIKEditField.Value;       % resolution of a partition (i.e., distance in IK fitness between two consecutives paritions)
                    app.gas.rankingSettings.step_len = app.SteplengthEditField.Value;
                case "bbbc"
                    app.bbbcs.MAX_GENERATIONS = app.GenerationsEditField.Value;
                    app.bbbcs.N = app.IndividualsEditField.Value;

                    app.bbbcs.crunchMethod = app.CrunchmethodDropDown.Value;   % 'fittest', 'com' -- fittest: choosing the fittest one as center of mass, com: calculating the center of mass with the equation on the paper

                    app.bbbcs.obstacle_avoidance = app.ObstacleavoidanceCheckBox.Value;
                    app.bbbcs.penalty_method = app.PenaltymethodDropDown.Value;	% 'static', 'deb'
                    
                    % settings of rank partitioning algorithm
                    app.bbbcs.ranking_method = app.MethodDropDown.Value;     % 'penalty', 'separation'
                    app.bbbcs.rankingSettings.step_ik = app.StepIKEditField.Value;       % resolution of a partition (i.e., distance in IK fitness between two consecutives paritions)
                    app.bbbcs.rankingSettings.step_len = app.SteplengthEditField.Value;
            end
        end
        
        function updateSurvivalAlphaVisibility(app,val)
            if val == "on"
                value = app.SurvivalmethodDropDown.Value;
                if value == "elitist_alpha"
                    app.SurvivalalphaEditField.Visible = "on";
                    app.SurvivalalphaLabel.Visible = "on";
                elseif value == "elitist_full"
                    app.SurvivalalphaEditField.Visible = "off";
                    app.SurvivalalphaLabel.Visible = "off";
                end
            end
        end
        

        function parseDataIntoMPTree(app)
            delete(app.MPTree.Children)
            children = app.loadedData.saveVar.MPTreeChildren;
            for i = 1 : numel(children)
                uitreenode(app.MPTree,"Text",children(i).Text,"NodeData",children(i).NodeData);
            end
            app.MPTree.SelectedNodes = app.MPTree.Children(end);
            MPTreeSelectionChanged(app,1);
        end
    end

    methods (Access = public)

        
        function drawTargetsObstaclesBaseinMP(app)
            selectedNode = app.MPTree.SelectedNodes;
            % draw base
            ps = draw_base(app,selectedNode.NodeData.base(1), selectedNode.NodeData.base(2), selectedNode.NodeData.base(3),app.UIAxes2);
            data = struct("x",selectedNode.NodeData.base(1),"y",selectedNode.NodeData.base(2));
            ps.UserData =data;
            ps.ButtonDownFcn = @(src,event)mouseClickInMP(app,src);
            % [~] = draw_base(app,0, 0, 0,app.UIAxes2);
            % draw obstacles
            for i = 1:numel(selectedNode.NodeData.obstacles(:,1))
                draw_obstacle(app,selectedNode.NodeData.obstacles(i,1),selectedNode.NodeData.obstacles(i,2),selectedNode.NodeData.obstacles(i,3),app.UIAxes2);

            end
            % draw targets
            for i = 1:numel(selectedNode.NodeData.targets(:,1))
                ps = draw_target(app,selectedNode.NodeData.targets(i,1),selectedNode.NodeData.targets(i,2),selectedNode.NodeData.targets(i,3),app.UIAxes2);
                data = struct("x",selectedNode.NodeData.targets(i,1),"y",selectedNode.NodeData.targets(i,2));
                ps.UserData =data;
                ps.ButtonDownFcn = @(src,event)mouseClickInMP(app,src);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % add parent folder to path
            try
                cd icons
                cd ..
                addpath(genpath("."))
            catch
                addpath(genpath(".."))
            end

            app.nChar = newline; % Description
            app.tabChar = char(9);
            axis(app.UIAxes1,'equal');
            axis(app.UIAxes2,'equal');
            hold(app.UIAxes1,"on");
            hold(app.UIAxes2,"on");
            add_base_plot_and_node(app,0,0,0);
            disable_properties(app);
            axtoolbar(app.UIAxes1,"default","Visible","off");
            axtoolbar(app.UIAxes2,"default","Visible","off");

            app.UIAxes1.XLim = app.XLim;
            app.UIAxes1.YLim = app.YLim;
            app.UIAxes2.XLim = app.XLim;
            app.UIAxes2.YLim = app.YLim;
            loadDefaultOptions(app)

            % Draw Min Max Lengths
            drawMinMax(app);

            % Rename window
            app.UIFigure.Name = "Soft Robot Optimizer";

            % Select Base
            select_child(app,app.BaseNode.Children(1).NodeData.child);

            % initalize uiaxes
            app.UIAxes = app.UIAxes1;
            
            % Fill nextAnimation.NodeData
            app.MPTree.SelectedNodes = app.MPTree.Children(end);
            TypeofalgorithmDropDownValueChanged(app, 0);
        
        end

        % Button down function: UIAxes1
        function UIAxes1ButtonDown3(app, event)
            currentPoint = app.UIAxes1.CurrentPoint;
             x = currentPoint(1,1);
             y = currentPoint(1,2);
             
             if app.Pointer.State=="on"
                 if ~isempty(app.lastSelected.Selected)
                     if app.lastSelected.Selected == "on"
                         ps_mouse_click(app,app.lastSelected);
                     end
                 end
             else
                 if numel(app.OPTree.Children)>1 && app.Eraser.State == "off"
                     result = confirmAction(app,'Editing',"Loss in Optimizer's Output Will Occur");
                     if result  == "OK"
                         delete(app.OPTree.Children);
                     else
                         return;
                     end

                 end
                 if app.Obstacle.State == "on"
                     
                     create_polyshape(app,x,y,1,0,false);
                     init_segments(app);
                     % change cursor to arrow
                     PointerOn(app,event);

                 elseif app.Target.State == "on"
                     create_polyshape(app,x,y,1,0,true);
                     init_segments(app);
                     % change cursor to arrow
                     PointerOn(app,event);
                 end
             end
                              
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            if selectedNodes ~= app.ObstaclesNode && selectedNodes ~= app.TargetsNode && selectedNodes~= app.BaseNode
            node_click(app);
            else
                disable_properties(app);
                deselect_children(app);
            end
        end

        % Value changed function: AngledegEditField, LengthEditField, 
        % ...and 2 other components
        function xcoordinateEditFieldValueChanged(app, event)
            if numel(app.OPTree.Children)>1
                result = confirmAction(app,'Editing',"Loss in Optimizer's Output Will Occur");
                if result  == "OK"
                    delete(app.OPTree.Children)
                else
                    return
                end
                
            end

            Apply(app);
            init_segments(app);

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)

        end

        % On callback: Pointer
        function PointerOn(app, event)
            % Pointer
            if app.Pointer.State == "off"
                app.Pointer.State = "on";
            end
            
            app.Target.State="off";
            app.Obstacle.State = "off";
            app.Eraser.State = "off";
            
            turn_children_hittest(app,"on")
            changePointerTo(app,"arrow");
            
            
        end

        % On callback: Obstacle
        function ObstacleOn(app, event)
            % Obstacle
            app.Pointer.State="off";
            app.Target.State = "off";
            app.Eraser.State = "off";
            % to be able to draw on them
            
            
            changePointerTo(app,"circle");
            turn_children_hittest(app,"off")
            
        end

        % Callback function: Eraser, Obstacle, Pointer, Target
        function PointerClicked(app, event)
            if event.Source.State == "off"
                event.Source.State = "on";
            end
            
        end

        % On callback: Target
        function TargetOn2(app, event)
            if app.Target.State == "off"
                app.Target.State = "on";
            end
            app.Pointer.State='off';
            app.Obstacle.State = 'off';
            app.Eraser.State = 'off';
            % To be able to draw on them
            turn_children_hittest(app,"off")
            changePointerTo(app,'target');
            
        end

        % On callback: Eraser
        function EraserOn(app, event)
            disable_properties(app);
            app.Pointer.State='off';
            app.Obstacle.State = 'off';
            app.Target.State = 'off';
            changePointerTo(app,'eraser');
            turn_children_hittest(app,"on")
            
        end

        % Callback function: RestoreView
        function RestoreViewClicked(app, event)
            app.UIAxes.XLim =  app.XLim;
            app.UIAxes.YLim =  app.YLim;
            drawMinMax(app);
        end

        % Callback function: Run
        function RunClicked(app, event)
            if numel(app.OPTree.Children)>1
                result = confirmAction(app,'Editing',"Loss in Optimizer's Output Will Occur");
                if result  == "OK"

                else
                    return;
                end

            end

            if numel(app.TargetsNode.Children)>0
                try
                    delete(app.OPTree.Children)
                    % disable all but stop
                    toggleUI(app, "off",[app.Stop]);
                    result = doesOverlap(app);
                    if ~result
                        app.stopRunItGeneric = false;
                        sendRunItGeneric(app);
                        [~,~] = runIt_generic(app);
                        % configurations = KeepNonZeroRows(app,configurations);
                        % drawRobotConfigurations(app,configurations);
                    end
                    toggleUI(app, "on", [app.Stop]);

                catch er
                    uialert(app.UIFigure, er.message, 'Error');

                end
            else
                fig = app.UIFigure;
                % msg = 'Please, first run the optimizer and then select a solution you would like to animate.';
                msg = 'No targets found.';
                title = 'Soft Robot Optimizer';
                uiconfirm(fig,msg,title, ...
                    'Options',{'OK'}, ...
                    'DefaultOption','OK','Icon','warning');
            end
        end

        % Callback function: PushTool4
        function PushTool4Clicked(app, event)
            remove_segment_lines(app)

        end

        % Value changed function: FirstangleEditField, 
        % ...and 4 other components
        function nodesEditFieldValueChanged2(app, event)
            init_segments(app);
        end

        % Callback function: PushTool2
        function PushTool2Clicked(app, event)
            changed = checkChanges(app);
            if changed
                saveVar.op = app.op;
                saveVar.OPTreeChildren = app.OPTree.Children;
                saveVar.MPTreeChildren = app.MPTree.Children;
                [file, path] = uiputfile('*.mat', 'MATLAB Data File');
                if isequal(file, 0)
                    % User canceled the operation
                else
                    % Combine the selected path and file name
                    fullFilePath = fullfile(path, file);

                    save(fullFilePath, 'saveVar');

                end
            end
        end

        % Callback function: PushTool
        function PushToolClicked(app, event)
            changed = checkChanges(app);
            if changed
                selection = confirmAction(app,'New Document',"Loss of previous changes may occur.");
                if selection ~= "OK"
                    return;  
                end

            end
            newDocument(app)

            loadDefaultOptions(app)

            drawMinMax(app);

            RestoreViewClicked(app, event)

            app.lastSelected = app.BaseNode.Children.NodeData.child;
        end

        % Callback function: PushTool3
        function PushTool3Clicked(app, event)
            changed = checkChanges(app);
            value = importFile(app);
            if value
                if changed
                    selection = confirmAction(app,'Open Document',"Loss of previous changes may occur.");
                    if selection ~= "OK"
                        return;

                    end
                end
                newDocument(app)
                parseDataIntoOP(app)
                parseDataIntoOPTree(app)
                parseDataIntoMPTree(app)
                app.Pointer.State = "on";
                % otherwise they'd not be clickable at first
                handle_hittest(app)
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            changed = checkChanges(app);
            if changed
                selection = confirmAction(app,'Exit',"Loss of previous changes may occur.");
                if selection == "OK"
                    delete(app)
                end
            else
                delete(app)
            end


        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            try
                % Set the zoom factor (adjust as needed)
                zoomFactor = 0.9;
            
                % Get the current cursor coordinates
                currentPoint = app.UIAxes.CurrentPoint;
                x = currentPoint(1,1);
                y = currentPoint(1,2);
                
                % Get the current axis limits
                currentXLimits = app.UIAxes.XLim;
                currentYLimits = app.UIAxes.YLim;
                
                % Check if cursor coordinates are inside the axes limits
                insideAxes = x >= currentXLimits(1) && x <= currentXLimits(2) && ...
                              y >= currentYLimits(1) && y <= currentYLimits(2);
                
                if insideAxes


                    if event.VerticalScrollCount > 0
                        % Calculate the new axis limits for zooming out while keeping the center point fixed
                        newXLimits = (currentXLimits - x) / zoomFactor + x;
                        newYLimits = (currentYLimits - y) / zoomFactor + y;
                    else
                        % Calculate the new axis limits for zooming in while keeping the center point fixed
                        newXLimits = (currentXLimits - x) * zoomFactor + x;
                        newYLimits = (currentYLimits - y) * zoomFactor + y;
                    end
            
                    % Update the axis limits
                    app.UIAxes.XLim = newXLimits;
                    app.UIAxes.YLim = newYLimits;
                    drawMinMax(app);
                end
            catch er
                uialert(app.UIFigure, er.message, 'Error');
            end            
        end

        % Value changed function: AlgorithmDropDown
        function AlgorithmDropDownValueChanged(app, event)
            value = app.AlgorithmDropDown.Value;
            if value=="Genetic Algorithm"
                bbbcOptionsVisibility(app,"off");
                gaOptionsVisibility(app,"on");
            elseif value == "Big-Bang Big-Crunch"
                bbbcOptionsVisibility(app,"on");
                gaOptionsVisibility(app,"off");
            end
        end

        % Callback function: Stop
        function StopClicked(app, event)
            app.stopRunItGeneric = true;
            toggleUI(app,"on",[app.Stop]);
            
            if numel(app.OPTree.Children)>0
                app.OPTree.SelectedNodes = app.OPTree.Children(end);
            end
            
            OPTreeSelectionChanged(app, event)

        end

        % Value changed function: SurvivalmethodDropDown
        function SurvivalmethodDropDownValueChanged(app, event)
            updateSurvivalAlphaVisibility(app, "on")
        end

        % Selection changed function: OPTree
        function OPTreeSelectionChanged(app, event)
            selectedNodes = app.OPTree.SelectedNodes;
            init_segments(app);
            drawRobotConfigurations(app,selectedNodes.NodeData.configurations)
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            if app.Pointer.State == "on"
                result = insideAxes(app);
                if result
                    app.UIFigure.WindowButtonMotionFcn = @(src,event)dragDropInMotion(app,src);
    
                    app.dragDropStart = app.UIAxes.CurrentPoint;
                end
            end
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
                app.UIFigure.WindowButtonMotionFcn = [];
                changePointerTo(app,app.latestTool);
        end

        % Callback function: Animate
        function AnimateClicked(app, event)
            
            selectedNode = app.OPTree.SelectedNodes;

            % OP's output is empty? Terminate and if not continue
            if isempty(selectedNode)
                fig = app.UIFigure;
                % msg = 'Please, first run the optimizer and then select a solution you would like to animate.';
                msg = '1. Run the optimizer.  2. select a solution you would like to animate.';
                title = 'Soft Robot Optimizer';
                uiconfirm(fig,msg,title, ...
                'Options',{'OK'}, ...
                'DefaultOption','OK','Icon','warning');
                return
            end
            
                
                % chnage tab 
                app.TabGroup3.SelectedTab = app.MotionPlannerTab;
        
                % fire the change in tabs event
                TabGroup3SelectionChanged(app, event)

                d = uiprogressdlg(app. UIFigure,'Title','Please Wait','Message','Searching for paths');
                d.Value = 0;
                pause(0.5)
                numberOfTargets = numel(app.op.targets(:,1));
                increment = 1/((numberOfTargets+1)*(numberOfTargets+1));

                paths=double.empty;

                numberOfResults = 0;
                home_conf = round(zeros(numel(app.OPTree.SelectedNodes.NodeData.design),2),3);
                app.OPTree.SelectedNodes.NodeData.configurations(:,:,end+1) = home_conf;

                for from = 1: numberOfTargets+1
                    for to = 1: numberOfTargets+1
                        collectSP(app,from,to);
                        loadSP(app.sp);
                        result = MP_Solution_2D();
                        if numel(result) > 0
                            numberOfResults = numberOfResults + 1;
                        end
                        paths{from}{to} = result;
                        if d.Value + increment < 1
                            d.Value = d.Value + increment;
                        end

                    end
                end
                d.Value = 1;
                d.Message = 'Done';
                pause(0.5)
                close(d)

                % found a path?
                if numberOfResults > 0
                    
                    % copy OPTree's node to MPTree
                    arrayfun(@(x,y) copyobj(flipud(app.OPTree.SelectedNodes),y),app.OPTree,app.MPTree)

                    % Copy old nextAnimation
                    uitreenode(app.MPTree,'NodeData',app.MPTree.Children(end-1).NodeData,'Text',app.MPTree.Children(end-1).Text);

                    % delete old nextAnimation
                    delete(app.MPTree.Children(end-2))

                    % click node
                    node = app.MPTree.Children(end-1);

                    % store sp in node
                    node.NodeData.sp = app.sp;
    
                    app.MPTree.SelectedNodes = node;
                    
                    % store result in NodeData.path
                    app.MPTree.SelectedNodes.NodeData.paths = paths;

                    % fire MPTree selectChanged event
                    MPTreeSelectionChanged(app, event)
                    
                    % change text
                    if isempty(app.animationsCount)
                        app.animationsCount = 1;
                    end
                    node.Text = "Animation #" + string(app.animationsCount);
                    app.animationsCount = app.animationsCount + 1;
                    
                    % draw segment lines
                    draw_segments(app,app.UIAxes2)
                else
                    fig = app.UIFigure;
                    % msg = 'Please, first run the optimizer and then select a solution you would like to animate.';
                    msg = 'No path found.';
                    title = 'Soft Robot Optimizer';
                    uiconfirm(fig,msg,title, ...
                    'Options',{'OK'}, ...
                    'DefaultOption','OK','Icon','info');
                end


        end

        % Selection change function: TabGroup3
        function TabGroup3SelectionChanged(app, event)
            selectedTab = app.TabGroup3.SelectedTab;
            if selectedTab == app.OptimizerTab
                app.UIAxes = app.UIAxes1;
            elseif selectedTab == app.MotionPlannerTab
                app.UIAxes = app.UIAxes2;
            end
            % change cursor to arrow
            PointerOn(app,event);
        end

        % Menu selected function: AnimateMenu
        function AnimateMenuSelected(app, event)
            AnimateClicked(app, event);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            if numel(app.MPTree.Children)>0
                node = app.MPTree.SelectedNodes;

                % disable everything
                toggleUI(app,"off",[])

                speed = app.SpeedperFrame1secto0secSlider.Value;
                secondsToPause = (100-speed)/100;
                paths = node.NodeData.paths;
                from = numel(node.NodeData.targets(:,1))+1;
                for i = 1 : numel(node.NodeData.targets(:,1))
                    to = from - 1;
                    app.MPRunning = true;
                    MP_softRobot_animation_2D(app,paths{from}{to},node.NodeData.base, true, node.NodeData.obstacles, app.UIAxes2,secondsToPause)
                    from = from-1;
                end
                % enable everything again
                toggleUI(app,"on",[app.Stop])
                app.previousX = round(node.NodeData.targets(1,1),3);
                app.previousY = round(node.NodeData.targets(1,2),3);
                % else
                %     fig = app.UIFigure;
                %     % msg = 'Please, first run the optimizer and then select a solution you would like to animate.';
                %     msg = 'No path found.';
                %     title = 'Soft Robot Optimizer';
                %     uiconfirm(fig,msg,title, ...
                %     'Options',{'OK'}, ...
                %     'DefaultOption','OK','Icon','info');
                % end
            else
                fig = app.UIFigure;
                % msg = 'Please, first run the optimizer and then select a solution you would like to animate.';
                msg = 'No animations found.';
                title = 'Soft Robot Optimizer';
                uiconfirm(fig,msg,title, ...
                    'Options',{'OK'}, ...
                    'DefaultOption','OK','Icon','info');
            end
            app.MPRunning = false;


        end

        % Selection changed function: MPTree
        function MPTreeSelectionChanged(app, event)
            if app.MPTree.SelectedNodes.Text == "Next animation"
                nextAnimation = app.MPTree.SelectedNodes;
                app.TypeofalgorithmDropDown.Value = nextAnimation.NodeData.typeOfAlg ;

                app.SteeringEditField.Value = nextAnimation.NodeData.costArray(1);
                app.GrowingEditField.Value = nextAnimation.NodeData.costArray(2);
                app.RetractingEditField.Value = nextAnimation.NodeData.costArray(3);

                app.SteeringEditField_2.Value = nextAnimation.NodeData.stepSize(1);
                app.GrowingandretractingEditField.Value = nextAnimation.NodeData.stepSize(2);

                app.TypeofheuristicDropDown.Value = nextAnimation.NodeData.typeOfHeuristic;

                app.SimultaneousCheckBox.Value = nextAnimation.NodeData.isSimulataneously;
                app.BaseRotateCheckBox.Value = nextAnimation.NodeData.baseRotate;

                % delete shapes in uiaxes2
                delete(app.UIAxes2.Children)
            else

                SPtoUI(app,app.MPTree.SelectedNodes.NodeData.sp);
                % delete everything in uiaxes2
                cla(app.UIAxes2)

                drawTargetsObstaclesBaseinMP(app)
                draw_segments(app,app.UIAxes2)
            end
            
        end

        % Menu selected function: CopyMenu
        function CopyMenuSelected(app, event)
            selected = app.lastSelected;

            if selected.Selected == "off"
                return;
            end

            if selected.UserData.Parent == app.TargetsNode
                app.clipboard.type = "target";
                app.clipboard.x = selected.UserData.NodeData.x;
                app.clipboard.y = selected.UserData.NodeData.y;
                app.clipboard.angle = selected.UserData.NodeData.angle;
            elseif selected.UserData.Parent == app.ObstaclesNode
                app.clipboard.type = "obstacle";
                app.clipboard.x = selected.UserData.NodeData.x;
                app.clipboard.y = selected.UserData.NodeData.y;
                app.clipboard.radius = selected.UserData.NodeData.radius;
            elseif selected.UserData.Parent == app.BaseNode
                app.clipboard.type = "base";
            end

        end

        % Menu selected function: PasteMenu
        function PasteMenuSelected(app, event)
            if (~isfield(app.clipboard,'type')) 
                return;
            end
            if app.clipboard.type == "obstacle"
                % Add obstacle from clipboard
                obstacle = app.clipboard;
                create_polyshape(app,obstacle.x+(obstacle.radius)*2+5,obstacle.y+(obstacle.radius)*2+5,obstacle.radius,0,false);
            elseif app.clipboard.type == "target"
                % Add target from clipboard
                target = app.clipboard;
                create_polyshape(app,target.x+5,target.y+5,1,target.angle,true);
            end
            init_segments(app);
            CopyMenuSelected(app,event)
        end

        % Menu selected function: CutMenu
        function CutMenuSelected(app, event)
            CopyMenuSelected(app,event);
            if app.Tree.SelectedNodes.Parent == app.ObstaclesNode || app.Tree.SelectedNodes.Parent == app.TargetsNode
                deleteSelected(app);
                app.lastSelected = app.BaseNode.Children(end).NodeData.child;
            end
        end

        % Value changed function: MinlengthEditField
        function MinlengthEditFieldValueChanged(app, event)
            drawMinMax(app);
        end

        % Value changed function: MaxlengthEditField
        function MaxlengthEditFieldValueChanged(app, event)
            drawMinMax(app);            
        end

        % Menu selected function: ExportasPDFMenu
        function ExportasPDFMenuSelected(app, event)
            [file, path] = uiputfile('*.pdf', 'PDF');
            if isequal(file, 0)
                % User canceled the operation
            else
                % Combine the selected path and file name
                fullFilePath = fullfile(path, file);
                d = uiprogressdlg(app. UIFigure,'Title','Please Wait','Message','Exporting as PDF');
                pause(0.5)
                exportgraphics(app.UIAxes1,fullFilePath,'ContentType','vector')                               
                d.Value = 1;
                d.Message = 'Done';
                pause(0.5)
                close(d)                                        
            end
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)

        end

        % Menu selected function: RunCase1Menu
        function RunCase1MenuSelected(app, event)
            runTestCase(app,1);
            drawMinMax(app);
        end

        % Menu selected function: RunCase2Menu
        function RunCase2MenuSelected(app, event)
            runTestCase(app,2);
            drawMinMax(app);
        end

        % Menu selected function: RunCase3Menu
        function RunCase3MenuSelected(app, event)
            runTestCase(app,3);
            drawMinMax(app);
        end

        % Menu selected function: RunCase4Menu
        function RunCase4MenuSelected(app, event)
            runTestCase(app,4);
            drawMinMax(app);
        end

        % Menu selected function: RunCase5Menu
        function RunCase5MenuSelected(app, event)
            runTestCase(app,5);
            drawMinMax(app);
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            PushToolClicked(app, event);
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            PushTool3Clicked(app, event);
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            PushTool2Clicked(app, event);
        end

        % Menu selected function: RunOptimizerMenu
        function RunOptimizerMenuSelected(app, event)
            RunClicked(app, event);
        end

        % Menu selected function: RunMotionPlannerMenu
        function RunMotionPlannerMenuSelected(app, event)
            AnimateClicked(app, event);
        end

        % Value changed function: BaseRotateCheckBox, GrowingEditField, 
        % ...and 6 other components
        function TypeofalgorithmDropDownValueChanged(app, event)
            selectedNode = app.MPTree.SelectedNodes;
            if selectedNode.Text == "Next animation"
                selectedNode.NodeData.typeOfAlg = app.TypeofalgorithmDropDown.Value;

                selectedNode.NodeData.costArray = [app.SteeringEditField.Value app.GrowingEditField.Value app.RetractingEditField.Value];
                selectedNode.NodeData.stepSize = [app.SteeringEditField_2.Value app.GrowingandretractingEditField.Value];

                selectedNode.NodeData.typeOfHeuristic = app.TypeofheuristicDropDown.Value;

                selectedNode.NodeData.isSimulataneously = app.SimultaneousCheckBox.Value;
                selectedNode.NodeData.baseRotate = app.BaseRotateCheckBox.Value;
            else
                selectedNode = app.MPTree.Children(end);
                confirm = confirmAction(app,"Motion Planner","Apply changes?");
                if confirm == "OK"
                    selectedNode.NodeData.typeOfAlg = app.TypeofalgorithmDropDown.Value;

                    selectedNode.NodeData.costArray = [app.SteeringEditField.Value app.GrowingEditField.Value app.RetractingEditField.Value];
                    selectedNode.NodeData.stepSize = [app.SteeringEditField_2.Value app.GrowingandretractingEditField.Value];

                    selectedNode.NodeData.typeOfHeuristic = app.TypeofheuristicDropDown.Value;

                    selectedNode.NodeData.isSimulataneously = app.SimultaneousCheckBox.Value;
                    selectedNode.NodeData.baseRotate = app.BaseRotateCheckBox.Value;

                    % add node to OPTree
                    
                    arrayfun(@(x,y) copyobj(flipud(app.MPTree.SelectedNodes),y),app.MPTree,app.OPTree)
                    
                    % click node
                    app.OPTree.SelectedNodes = app.OPTree.Children(end);

                    % call animator
                    AnimateClicked(app,event)

                    % delete node
                    delete(app.OPTree.Children(end))
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Accelerator = 'n';
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Accelerator = 'o';
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Accelerator = 's';
            app.SaveMenu.Text = 'Save';
            app.ExportasPDFMenu = uimenu(app.FileMenu);
            app.ExportasPDFMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportasPDFMenuSelected, true);
            app.ExportasPDFMenu.Accelerator = 'p';
            app.ExportasPDFMenu.Text = 'Export as PDF';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.CutMenu = uimenu(app.EditMenu);
            app.CutMenu.MenuSelectedFcn = createCallbackFcn(app, @CutMenuSelected, true);
            app.CutMenu.Accelerator = 'x';
            app.CutMenu.Text = 'Cut';
            app.CopyMenu = uimenu(app.EditMenu);
            app.CopyMenu.MenuSelectedFcn = createCallbackFcn(app, @CopyMenuSelected, true);
            app.CopyMenu.Accelerator = 'c';
            app.CopyMenu.Text = 'Copy';
            app.PasteMenu = uimenu(app.EditMenu);
            app.PasteMenu.MenuSelectedFcn = createCallbackFcn(app, @PasteMenuSelected, true);
            app.PasteMenu.Accelerator = 'v';
            app.PasteMenu.Text = 'Paste';
            app.RunMenu = uimenu(app.UIFigure);
            app.RunMenu.Text = 'Run';
            app.RunOptimizerMenu = uimenu(app.RunMenu);
            app.RunOptimizerMenu.MenuSelectedFcn = createCallbackFcn(app, @RunOptimizerMenuSelected, true);
            app.RunOptimizerMenu.Accelerator = 'r';
            app.RunOptimizerMenu.Text = 'Run Optimizer';
            app.RunMotionPlannerMenu = uimenu(app.RunMenu);
            app.RunMotionPlannerMenu.MenuSelectedFcn = createCallbackFcn(app, @RunMotionPlannerMenuSelected, true);
            app.RunMotionPlannerMenu.Accelerator = 't';
            app.RunMotionPlannerMenu.Text = 'Run Motion Planner';
            app.TestsMenu = uimenu(app.UIFigure);
            app.TestsMenu.Text = 'Tests';
            app.RunCase1Menu = uimenu(app.TestsMenu);
            app.RunCase1Menu.MenuSelectedFcn = createCallbackFcn(app, @RunCase1MenuSelected, true);
            app.RunCase1Menu.Text = 'Run Case 1';
            app.RunCase2Menu = uimenu(app.TestsMenu);
            app.RunCase2Menu.MenuSelectedFcn = createCallbackFcn(app, @RunCase2MenuSelected, true);
            app.RunCase2Menu.Text = 'Run Case 2';
            app.RunCase3Menu = uimenu(app.TestsMenu);
            app.RunCase3Menu.MenuSelectedFcn = createCallbackFcn(app, @RunCase3MenuSelected, true);
            app.RunCase3Menu.Text = 'Run Case 3';
            app.RunCase4Menu = uimenu(app.TestsMenu);
            app.RunCase4Menu.MenuSelectedFcn = createCallbackFcn(app, @RunCase4MenuSelected, true);
            app.RunCase4Menu.Text = 'Run Case 4';
            app.RunCase5Menu = uimenu(app.TestsMenu);
            app.RunCase5Menu.MenuSelectedFcn = createCallbackFcn(app, @RunCase5MenuSelected, true);
            app.RunCase5Menu.Text = 'Run Case 5';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'New document'};
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = 'add-document.png';
            app.PushTool.Separator = 'on';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.Tooltip = {'Save document'};
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Icon = 'disk.png';
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.Tooltip = {'Open document'};
            app.PushTool3.ClickedCallback = createCallbackFcn(app, @PushTool3Clicked, true);
            app.PushTool3.Icon = 'folder-open.png';
            app.Pointer = uitoggletool(app.Toolbar);
            app.Pointer.Tooltip = {'Select'};
            app.Pointer.ClickedCallback = createCallbackFcn(app, @PointerClicked, true);
            app.Pointer.Icon = 'cursor.png';
            app.Pointer.Separator = 'on';
            app.Pointer.OnCallback = createCallbackFcn(app, @PointerOn, true);
            app.Pointer.State = 'on';
            app.Target = uitoggletool(app.Toolbar);
            app.Target.Tooltip = {'Target'};
            app.Target.ClickedCallback = createCallbackFcn(app, @PointerClicked, true);
            app.Target.Icon = 'star.png';
            app.Target.OnCallback = createCallbackFcn(app, @TargetOn2, true);
            app.Obstacle = uitoggletool(app.Toolbar);
            app.Obstacle.Tooltip = {'Obstacle'};
            app.Obstacle.ClickedCallback = createCallbackFcn(app, @PointerClicked, true);
            app.Obstacle.Icon = 'circle.png';
            app.Obstacle.OnCallback = createCallbackFcn(app, @ObstacleOn, true);
            app.Eraser = uitoggletool(app.Toolbar);
            app.Eraser.Tooltip = {'Eraser'};
            app.Eraser.ClickedCallback = createCallbackFcn(app, @PointerClicked, true);
            app.Eraser.Icon = 'eraser.png';
            app.Eraser.OnCallback = createCallbackFcn(app, @EraserOn, true);
            app.Run = uipushtool(app.Toolbar);
            app.Run.Tooltip = {'Run Optimizer'};
            app.Run.ClickedCallback = createCallbackFcn(app, @RunClicked, true);
            app.Run.Icon = 'play.png';
            app.Run.Separator = 'on';
            app.Stop = uipushtool(app.Toolbar);
            app.Stop.ClickedCallback = createCallbackFcn(app, @StopClicked, true);
            app.Stop.Enable = 'off';
            app.Stop.Icon = 'stop.png';
            app.PushTool4 = uipushtool(app.Toolbar);
            app.PushTool4.Tooltip = {'Clean'};
            app.PushTool4.ClickedCallback = createCallbackFcn(app, @PushTool4Clicked, true);
            app.PushTool4.Icon = 'broom.png';
            app.Animate = uipushtool(app.Toolbar);
            app.Animate.Tooltip = {'Run Motion Planner'};
            app.Animate.ClickedCallback = createCallbackFcn(app, @AnimateClicked, true);
            app.Animate.Icon = 'animated-icon.png';
            app.RestoreView = uipushtool(app.Toolbar);
            app.RestoreView.Tooltip = {'Restore view'};
            app.RestoreView.ClickedCallback = createCallbackFcn(app, @RestoreViewClicked, true);
            app.RestoreView.Icon = 'house-chimney.png';
            app.RestoreView.Separator = 'on';
            app.GridLayout6 = uigridlayout(app.UIFigure);
            app.TabGroup3 = uitabgroup(app.GridLayout6);
            app.TabGroup3.SelectionChangedFcn = createCallbackFcn(app, @TabGroup3SelectionChanged, true);
            app.OptimizerTab = uitab(app.TabGroup3);
            app.OptimizerTab.Title = 'Optimizer';
            app.GridLayout = uigridlayout(app.OptimizerTab);
            app.UIAxes1 = uiaxes(app.GridLayout);
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes1.ButtonDownFcn = createCallbackFcn(app, @UIAxes1ButtonDown3, true);
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.OPTree = uitree(app.GridLayout);
            app.OPTree.SelectionChangedFcn = createCallbackFcn(app, @OPTreeSelectionChanged, true);
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '';
            app.ObjectsLabel = uilabel(app.GridLayout);
            app.ObjectsLabel.Text = 'Objects';
            app.Tree = uitree(app.GridLayout);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.BaseNode = uitreenode(app.Tree);
            app.BaseNode.Icon = 'house-chimney.png';
            app.BaseNode.Text = 'Base';
            app.TargetsNode = uitreenode(app.Tree);
            app.TargetsNode.Icon = 'star.png';
            app.TargetsNode.Text = 'Targets';
            app.ObstaclesNode = uitreenode(app.Tree);
            app.ObstaclesNode.Icon = 'circle.png';
            app.ObstaclesNode.Text = 'Obstacles';
            app.Label_3 = uilabel(app.GridLayout);
            app.Label_3.Text = '';
            app.OptionsLabel = uilabel(app.GridLayout);
            app.OptionsLabel.Text = 'Options';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.PropertiesTab = uitab(app.TabGroup);
            app.PropertiesTab.Title = 'Properties';
            app.GridLayout3 = uigridlayout(app.PropertiesTab);
            app.XcoordinateEditFieldLabel = uilabel(app.GridLayout3);
            app.XcoordinateEditFieldLabel.Text = 'X-coordinate ';
            app.XcoordinateEditField = uieditfield(app.GridLayout3, 'numeric');
            app.XcoordinateEditField.ValueChangedFcn = createCallbackFcn(app, @xcoordinateEditFieldValueChanged, true);
            app.YcoordinateEditFieldLabel = uilabel(app.GridLayout3);
            app.YcoordinateEditFieldLabel.Text = 'Y-coordinate ';
            app.YcoordinateEditField = uieditfield(app.GridLayout3, 'numeric');
            app.YcoordinateEditField.ValueChangedFcn = createCallbackFcn(app, @xcoordinateEditFieldValueChanged, true);
            app.LengthEditFieldLabel = uilabel(app.GridLayout3);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.GridLayout3, 'numeric');
            app.LengthEditField.Limits = [0 Inf];
            app.LengthEditField.ValueChangedFcn = createCallbackFcn(app, @xcoordinateEditFieldValueChanged, true);
            app.AngledegEditFieldLabel = uilabel(app.GridLayout3);
            app.AngledegEditFieldLabel.Text = 'Angle (deg)';
            app.AngledegEditField = uieditfield(app.GridLayout3, 'numeric');
            app.AngledegEditField.Limits = [-360 360];
            app.AngledegEditField.ValueChangedFcn = createCallbackFcn(app, @xcoordinateEditFieldValueChanged, true);
            app.Label_4 = uilabel(app.GridLayout);
            app.Label_4.Text = '';
            app.TabGroup2 = uitabgroup(app.GridLayout);
            app.RobotParametersTab = uitab(app.TabGroup2);
            app.RobotParametersTab.Title = 'Robot Parameters';
            app.GridLayout2 = uigridlayout(app.RobotParametersTab);
            app.FixedfirstangleCheckBox = uicheckbox(app.GridLayout2);
            app.FixedfirstangleCheckBox.ValueChangedFcn = createCallbackFcn(app, @nodesEditFieldValueChanged2, true);
            app.FixedfirstangleCheckBox.Text = 'Fixed first angle';
            app.LinksEditFieldLabel = uilabel(app.GridLayout2);
            app.LinksEditFieldLabel.Text = 'Links';
            app.LinksEditField = uieditfield(app.GridLayout2, 'numeric');
            app.LinksEditField.Limits = [0 Inf];
            app.LinksEditField.ValueChangedFcn = createCallbackFcn(app, @nodesEditFieldValueChanged2, true);
            app.MinangleEditFieldLabel = uilabel(app.GridLayout2);
            app.MinangleEditFieldLabel.Text = 'Min angle';
            app.MinangleEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MinangleEditField.Limits = [-360 360];
            app.MinangleEditField.ValueChangedFcn = createCallbackFcn(app, @nodesEditFieldValueChanged2, true);
            app.MaxangleEditFieldLabel = uilabel(app.GridLayout2);
            app.MaxangleEditFieldLabel.Text = 'Max angle';
            app.MaxangleEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MaxangleEditField.Limits = [-360 360];
            app.MaxangleEditField.ValueChangedFcn = createCallbackFcn(app, @nodesEditFieldValueChanged2, true);
            app.MinlengthEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MinlengthEditField.Limits = [0 Inf];
            app.MinlengthEditField.ValueChangedFcn = createCallbackFcn(app, @MinlengthEditFieldValueChanged, true);
            app.MinlengthEditFieldLabel = uilabel(app.GridLayout2);
            app.MinlengthEditFieldLabel.Text = 'Min length';
            app.MaxlengthEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MaxlengthEditField.Limits = [0 Inf];
            app.MaxlengthEditField.ValueChangedFcn = createCallbackFcn(app, @MaxlengthEditFieldValueChanged, true);
            app.MaxlengthEditFieldLabel = uilabel(app.GridLayout2);
            app.MaxlengthEditFieldLabel.Text = 'Max length';
            app.FirstangleEditFieldLabel = uilabel(app.GridLayout2);
            app.FirstangleEditFieldLabel.Text = 'First angle';
            app.FirstangleEditField = uieditfield(app.GridLayout2, 'numeric');
            app.FirstangleEditField.Limits = [-360 360];
            app.FirstangleEditField.ValueChangedFcn = createCallbackFcn(app, @nodesEditFieldValueChanged2, true);
            app.OptimizationAlgorithmTab = uitab(app.TabGroup2);
            app.OptimizationAlgorithmTab.Title = 'Optimization Algorithm';
            app.GridLayout4 = uigridlayout(app.OptimizationAlgorithmTab);
            app.ObstacleavoidanceCheckBox = uicheckbox(app.GridLayout4);
            app.ObstacleavoidanceCheckBox.Text = 'Obstacle avoidance';
            app.SlectionmethodDropDownLabel = uilabel(app.GridLayout4);
            app.SlectionmethodDropDownLabel.Text = 'Slection method';
            app.SlectionmethodDropDown = uidropdown(app.GridLayout4);
            app.SlectionmethodDropDown.Items = {'tournament', 'proportionate'};
            app.SlectionmethodDropDown.Value = 'tournament';
            app.MutationprobabilitySpinnerLabel = uilabel(app.GridLayout4);
            app.MutationprobabilitySpinnerLabel.Text = 'Mutation probability';
            app.MutationprobabilitySpinner = uispinner(app.GridLayout4);
            app.MutationprobabilitySpinner.Step = 0.1;
            app.MutationprobabilitySpinner.Limits = [0 1];
            app.SurvivalmethodDropDownLabel = uilabel(app.GridLayout4);
            app.SurvivalmethodDropDownLabel.Text = 'Survival method';
            app.SurvivalmethodDropDown = uidropdown(app.GridLayout4);
            app.SurvivalmethodDropDown.Items = {'elitist_full', 'elitist_alpha'};
            app.SurvivalmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @SurvivalmethodDropDownValueChanged, true);
            app.SurvivalmethodDropDown.Value = 'elitist_full';
            app.SurvivalalphaLabel = uilabel(app.GridLayout4);
            app.SurvivalalphaLabel.Text = 'Survival alpha %';
            app.SurvivalalphaEditField = uieditfield(app.GridLayout4, 'numeric');
            app.SurvivalalphaEditField.Limits = [0 100];
            app.AlgorithmDropDownLabel = uilabel(app.GridLayout4);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.GridLayout4);
            app.AlgorithmDropDown.Items = {'Genetic Algorithm', 'Big-Bang Big-Crunch'};
            app.AlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @AlgorithmDropDownValueChanged, true);
            app.AlgorithmDropDown.Value = 'Genetic Algorithm';
            app.GenerationsEditField = uieditfield(app.GridLayout4, 'numeric');
            app.GenerationsEditField.Limits = [0 Inf];
            app.GenerationsEditFieldLabel = uilabel(app.GridLayout4);
            app.GenerationsEditFieldLabel.Text = 'Generations';
            app.IndividualsEditField = uieditfield(app.GridLayout4, 'numeric');
            app.IndividualsEditField.Limits = [0 Inf];
            app.IndividualsEditFieldLabel = uilabel(app.GridLayout4);
            app.IndividualsEditFieldLabel.Text = 'Individuals';
            app.PenaltymethodDropDown = uidropdown(app.GridLayout4);
            app.PenaltymethodDropDown.Items = {'static', 'deb'};
            app.PenaltymethodDropDown.Value = 'static';
            app.PenaltymethodDropDownLabel = uilabel(app.GridLayout4);
            app.PenaltymethodDropDownLabel.Text = 'Penalty method';
            app.CrossovermethodDropDown = uidropdown(app.GridLayout4);
            app.CrossovermethodDropDown.Items = {'blxa', 'sbx'};
            app.CrossovermethodDropDown.Value = 'blxa';
            app.CrossovermethodDropDownLabel = uilabel(app.GridLayout4);
            app.CrossovermethodDropDownLabel.Text = 'Crossover method';
            app.CrossoverprobabilitySpinner = uispinner(app.GridLayout4);
            app.CrossoverprobabilitySpinner.Step = 0.1;
            app.CrossoverprobabilitySpinner.Limits = [0 1];
            app.CrossoverprobabilitySpinnerLabel = uilabel(app.GridLayout4);
            app.CrossoverprobabilitySpinnerLabel.Text = 'Crossover probability';
            app.MutationmethodDropDown = uidropdown(app.GridLayout4);
            app.MutationmethodDropDown.Items = {'random', 'modified random'};
            app.MutationmethodDropDown.Value = 'random';
            app.MutationmethodDropDownLabel = uilabel(app.GridLayout4);
            app.MutationmethodDropDownLabel.Text = 'Mutation method';
            app.CrunchmethodDropDown = uidropdown(app.GridLayout4);
            app.CrunchmethodDropDown.Items = {'fittest', 'com'};
            app.CrunchmethodDropDown.Visible = 'off';
            app.CrunchmethodDropDown.Value = 'fittest';
            app.CrunchmethodDropDownLabel = uilabel(app.GridLayout4);
            app.CrunchmethodDropDownLabel.Visible = 'off';
            app.CrunchmethodDropDownLabel.Text = 'Crunch method';
            app.RankAlgorithmTab = uitab(app.TabGroup2);
            app.RankAlgorithmTab.Title = 'Rank Algorithm';
            app.GridLayout5 = uigridlayout(app.RankAlgorithmTab);
            app.MethodDropDownLabel = uilabel(app.GridLayout5);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.GridLayout5);
            app.MethodDropDown.Items = {'penalty'};
            app.MethodDropDown.Value = 'penalty';
            app.SteplengthEditFieldLabel = uilabel(app.GridLayout5);
            app.SteplengthEditFieldLabel.Text = 'Step length';
            app.SteplengthEditField = uieditfield(app.GridLayout5, 'numeric');
            app.SteplengthEditField.Limits = [0 Inf];
            app.StepIKEditField = uieditfield(app.GridLayout5, 'numeric');
            app.StepIKEditField.Limits = [0 Inf];
            app.StepIKEditFieldLabel = uilabel(app.GridLayout5);
            app.StepIKEditFieldLabel.Text = 'Step IK';
            app.MotionPlannerTab = uitab(app.TabGroup3);
            app.MotionPlannerTab.Title = 'Motion Planner';
            app.GridLayout7 = uigridlayout(app.MotionPlannerTab);
            app.UIAxes2 = uiaxes(app.GridLayout7);
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.TabGroup4 = uitabgroup(app.GridLayout7);
            app.StepSizeTab = uitab(app.TabGroup4);
            app.StepSizeTab.Title = 'Step Size';
            app.GridLayout3_3 = uigridlayout(app.StepSizeTab);
            app.SteeringEditField_2Label = uilabel(app.GridLayout3_3);
            app.SteeringEditField_2Label.Text = 'Steering';
            app.SteeringEditField_2 = uieditfield(app.GridLayout3_3, 'numeric');
            app.SteeringEditField_2.ValueChangedFcn = createCallbackFcn(app, @TypeofalgorithmDropDownValueChanged, true);
            app.GrowingandretractingEditFieldLabel = uilabel(app.GridLayout3_3);
            app.GrowingandretractingEditFieldLabel.Text = 'Growing and retracting';
            app.GrowingandretractingEditField = uieditfield(app.GridLayout3_3, 'numeric');
            app.GrowingandretractingEditField.ValueChangedFcn = createCallbackFcn(app, @TypeofalgorithmDropDownValueChanged, true);
            app.TypeofalgorithmDropDownLabel = uilabel(app.GridLayout3_3);
            app.TypeofalgorithmDropDownLabel.Visible = 'off';
            app.TypeofalgorithmDropDownLabel.Text = 'Type of algorithm';
            app.TypeofalgorithmDropDown = uidropdown(app.GridLayout3_3);
            app.TypeofalgorithmDropDown.Items = {'A*', 'UCS', 'Greedy'};
            app.TypeofalgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeofalgorithmDropDownValueChanged, true);
            app.TypeofalgorithmDropDown.Visible = 'off';
            app.TypeofalgorithmDropDown.Value = 'A*';
            app.TypeofheuristicDropDownLabel = uilabel(app.GridLayout3_3);
            app.TypeofheuristicDropDownLabel.Visible = 'off';
            app.TypeofheuristicDropDownLabel.Text = 'Type of heuristic';
            app.TypeofheuristicDropDown = uidropdown(app.GridLayout3_3);
            app.TypeofheuristicDropDown.Items = {'Continuous'};
            app.TypeofheuristicDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeofalgorithmDropDownValueChanged, true);
            app.TypeofheuristicDropDown.Visible = 'off';
            app.TypeofheuristicDropDown.Value = 'Continuous';
            app.SimultaneousCheckBox = uicheckbox(app.GridLayout3_3);
            app.SimultaneousCheckBox.ValueChangedFcn = createCallbackFcn(app, @TypeofalgorithmDropDownValueChanged, true);
            app.SimultaneousCheckBox.Visible = 'off';
            app.SimultaneousCheckBox.Text = 'Simultaneous';
            app.BaseRotateCheckBox = uicheckbox(app.GridLayout3_3);
            app.BaseRotateCheckBox.ValueChangedFcn = createCallbackFcn(app, @TypeofalgorithmDropDownValueChanged, true);
            app.BaseRotateCheckBox.Visible = 'off';
            app.BaseRotateCheckBox.Text = 'Base Rotate';
            app.SteeringEditField_3Label = uilabel(app.GridLayout3_3);
            app.SteeringEditField_3Label.Visible = 'off';
            app.SteeringEditField_3Label.Text = 'Steering';
            app.SteeringEditField = uieditfield(app.GridLayout3_3, 'numeric');
            app.SteeringEditField.ValueChangedFcn = createCallbackFcn(app, @TypeofalgorithmDropDownValueChanged, true);
            app.SteeringEditField.Visible = 'off';
            app.GrowingEditFieldLabel = uilabel(app.GridLayout3_3);
            app.GrowingEditFieldLabel.Visible = 'off';
            app.GrowingEditFieldLabel.Text = 'Growing';
            app.GrowingEditField = uieditfield(app.GridLayout3_3, 'numeric');
            app.GrowingEditField.ValueChangedFcn = createCallbackFcn(app, @TypeofalgorithmDropDownValueChanged, true);
            app.GrowingEditField.Visible = 'off';
            app.RetractingEditFieldLabel = uilabel(app.GridLayout3_3);
            app.RetractingEditFieldLabel.Visible = 'off';
            app.RetractingEditFieldLabel.Text = 'Retracting';
            app.RetractingEditField = uieditfield(app.GridLayout3_3, 'numeric');
            app.RetractingEditField.Limits = [0 Inf];
            app.RetractingEditField.Visible = 'off';
            app.MPTree = uitree(app.GridLayout7);
            app.MPTree.SelectionChangedFcn = createCallbackFcn(app, @MPTreeSelectionChanged, true);
            app.NextanimationNode = uitreenode(app.MPTree);
            app.NextanimationNode.Text = 'Next animation';
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.Button_5 = uibutton(app.GridLayout8, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = 'play2.png';
            app.Button_5.Text = '';
            app.SpeedperFrame1secto0secSliderLabel = uilabel(app.GridLayout8);
            app.SpeedperFrame1secto0secSliderLabel.Text = 'Speed per Frame (1 sec to 0 sec)';
            app.SpeedperFrame1secto0secSlider = uislider(app.GridLayout8);
            app.SpeedperFrame1secto0secSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.SpeedperFrame1secto0secSlider.MajorTickLabels = {'0', '10', '20', '30', '40', '50', '60', '70', '80', '90', '100', '', '0'};
            app.SpeedperFrame1secto0secSlider.MinorTicks = [];
            app.AnimationsLabel = uilabel(app.GridLayout7);
            app.AnimationsLabel.Text = 'Animations';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.AnimateMenu = uimenu(app.ContextMenu);
            app.AnimateMenu.MenuSelectedFcn = createCallbackFcn(app, @AnimateMenuSelected, true);
            app.AnimateMenu.Text = 'Animate';
            % Assign app.ContextMenu
            app.OPTree.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app4_UI_Prototype

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end